classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        EditField  matlab.ui.control.EditField
        Durdur     matlab.ui.control.Button
        Baslat     matlab.ui.control.Button
        UIAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a 
        stop
        uzaklik
        time % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global a;
            a= serial("COM3","BaudRate",9600,"Terminator","LF");
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            
        end

        % Button pushed function: Baslat
        function BaslatPushed(app, event)
            app.a = animatedline(app.UIAxes);
            global time uzaklik
            app.stop= false;
            
            
                uzaklik= fscanf(app.a);
                time= datetime("now");
                plot(app.UIAxes,uzaklik)
                app.UIAxes.XLim= [0 1000];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Zamana Göre Uzaklık')
            xlabel(app.UIAxes, 'Zaman(sn)')
            ylabel(app.UIAxes, 'Uzaklık-Mesafe(cm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 3 4 5 6];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '25'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '22'; '3'; '4'; '5'; '6'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Baslat = uibutton(app.UIFigure, 'push');
            app.Baslat.ButtonPushedFcn = createCallbackFcn(app, @BaslatPushed, true);
            app.Baslat.Text = 'Başlat';
            app.Durdur = uibutton(app.UIFigure, 'push');
            app.Durdur.Text = 'Durdur';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'ZAMANA GÖRE UZAKLIK ÖLÇME GRAFİĞİ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end